classdef SQLAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NEXTButton                      matlab.ui.control.Button
        BACKButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SQLsearchTab                    matlab.ui.container.Tab
        AutomaticallygeneratecodeLabel  matlab.ui.control.Label
        FindAuthorsButton               matlab.ui.control.Button
        FindPapersButton                matlab.ui.control.Button
        SQLTextArea                     matlab.ui.control.TextArea
        SQLTextAreaLabel                matlab.ui.control.Label
        KeyWordssearchTab               matlab.ui.container.Tab
        OrderByDropDown                 matlab.ui.control.DropDown
        OrderByDropDownLabel            matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        UnionButton                     matlab.ui.control.RadioButton
        IntersectButton                 matlab.ui.control.RadioButton
        KeyWords3EditField              matlab.ui.control.EditField
        KeyWords3EditFieldLabel         matlab.ui.control.Label
        KeyWords2EditField              matlab.ui.control.EditField
        KeyWords2EditFieldLabel         matlab.ui.control.Label
        SearchByDropDown                matlab.ui.control.DropDown
        SearchByLabel                   matlab.ui.control.Label
        KeyWords1EditField              matlab.ui.control.EditField
        KeyWords1EditFieldLabel         matlab.ui.control.Label
        PageDropDown                    matlab.ui.control.DropDown
        PageDropDownLabel               matlab.ui.control.Label
        TotalEditField                  matlab.ui.control.NumericEditField
        TotalEditFieldLabel             matlab.ui.control.Label
        resetButton                     matlab.ui.control.Button
        exitButton                      matlab.ui.control.Button
        SEARCHButton                    matlab.ui.control.Button
        CONNECTButton                   matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        FILEEditField                   matlab.ui.control.EditField
        FILEEditFieldLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        db; % Description
        conn; % Description
        sql; % Description
        isConn = false; % Description
        n=10; % Description
        data;
        currentPage;
    end
    
    methods (Access = private)        
        function testButtonEnable(app)
            if length(app.PageDropDown.Items) > 1 && str2double(app.PageDropDown.Value) < length(app.PageDropDown.Items)
                app.NEXTButton.Enable = true; 
            else
                app.NEXTButton.Enable = false; 
            end
            if str2double(app.PageDropDown.Value) >1 
                app.BACKButton.Enable = true;                
            else
                app.BACKButton.Enable = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FILEEditField
        function FILEEditFieldValueChanged(app, event)
            value = app.FILEEditField.Value;
            
        end

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
            app.db = app.FILEEditField.Value;
            if ~app.isConn
                try
                    app.conn = sqlite(app.db,'readonly');                 
                catch 
                    msgbox("Can't connect (missing database?)");
                    app.isConn=false;
                    return;
                end
                msgbox("success connect !");            
                app.isConn=true;
            end
        end

        % Button pushed function: SEARCHButton
        function SEARCHButtonPushed(app, event)
             if(app.isConn) 
                selectedTab = app.TabGroup.SelectedTab;
                if(string(selectedTab.Title)=="SQL search")
                    app.sql = string(app.SQLTextArea.Value);                
                    try
                        result = fetch(app.conn,app.sql);
                    catch
                        msgbox("error sql statement");                    
                        return;                    
                    end                    
                else
                    searchBy = app.SearchByDropDown.Value;
                    orderBy = app.OrderByDropDown.Value;
                    keyWord1 = app.KeyWords1EditField.Value;                    
                    keyWord2 = app.KeyWords2EditField.Value;
                    keyWord3 = app.KeyWords3EditField.Value;
                    s=" where ";
                    s1=strcat(searchBy," like '%",keyWord1,"%'");
                    s4=strcat(" order by ",orderBy);                    
                    if isempty(keyWord1)
                        msgbox("KeyWord1 cannot empty.");
                        return;
                    end   
                    if isempty(keyWord2)
                        s2="";
                    else
                        s2=strcat(" or ",searchBy," like '%",keyWord2,"%'");
                    end    
                    if isempty(keyWord3)
                        s3="";
                    else
                        s3=strcat(" or ",searchBy," like '%",keyWord3,"%'");
                    end 
                    type = app.ButtonGroup.SelectedObject.Text;                    
                    totalTable = "(Authors left join PaperAuthors on Authors.ID = PaperAuthors.AuthorID) left join Papers on PaperAuthors.PaperID = Papers.ID";
                    if type=="Union"
                        tempSql = strcat("select authors.id,name,title,abstract,eventtype from ",totalTable,s,s1,s2,s3,s4);
                    else%intercept
                        if isempty(keyWord2)
                            s2="";
                        else
                            s2=strcat(" and ",searchBy," like '%",keyWord2,"%'");
                        end    
                        if isempty(keyWord3)
                            s3="";
                        else
                            s3=strcat(" and ",searchBy," like '%",keyWord3,"%'");
                        end 
                        tempSql = strcat("select authors.id,name,title,abstract,eventtype from ",totalTable,s,s1,s2,s3,s4);
                    end                    
                    %tempSql = strcat("select authors.id,name,title,abstract,eventtype from ",totalTable," where ",searchBy," like '%",keyWord1,"%'","order by ",orderBy);
                    try
                        result = fetch(app.conn,tempSql);
                        app.UITable.ColumnName = ["ID","NAME","TITLE","ABSTRACT","EVENT TYPE"];
                    catch
                        msgbox("error sql statement");                    
                        return;                    
                    end
                end
                if isempty(result)                  
                        app.db= "";                                                         
                        app.UITable.Data="";                    
                        app.data="";
                        app.n=10;
                        app.PageDropDown.Items="";
                        app.PageDropDown.Value=""; 
                        app.currentPage="";
                        app.TotalEditField.Value=0;
                        msgbox("no data found");                                        
                        return;
                    end
                    result = cell2table(result);                
                    app.data=result;
                    app.UITable.RowName=num2cell(1:size(result,1));                 
                    app.TotalEditField.Value = size(result,1);                
                    app.PageDropDown.Items = string(1:size(result,1)/app.n);
                    app.currentPage = 1;
                    app.UITable.Data=result;  
                    testButtonEnable(app);
            else
                msgbox("not connect");
            end    
            
        end

        % Button pushed function: exitButton
        function exitButtonPushed(app, event)
            if app.isConn 
                close(app.conn);
            end
            delete(app);
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.SQLTextArea.Value = "";
            app.db= "";
            close(app.conn);
            app.sql = "";
            %app.FILEEditField.Value="";
            app.UITable.Data="";
            app.isConn=false;
            app.data="";
            app.n=10;
            app.PageDropDown.Items="";
            app.PageDropDown.Value="";   
            app.currentPage="";
            app.NEXTButton.Enable =false;
            app.BACKButton.Enable =false;
            app.TotalEditField.Value=0;
            %app.title.Value="";
            %app.AbstractTextArea.Value="";
            %app.PaperText.Value="";    
            app.SearchByDropDown.Value=app.SearchByDropDown.Items{1};
            app.OrderByDropDown.Value=app.OrderByDropDown.Items{1};
            app.ButtonGroup.SelectedObject=app.ButtonGroup.Children(2);
            app.KeyWords1EditField.Value="";
            app.KeyWords2EditField.Value="";
            app.KeyWords3EditField.Value="";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close(app.conn);
            delete(app);            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
%            newDisplayData = app.UITable.DisplayData;                       
            
            
        end

        % Value changed function: PageDropDown
        function PageDropDownValueChanged(app, event)
            value = str2num(app.PageDropDown.Value);
            value = value * app.n;
            app.UITable.Data = app.data(value-app.n+1:end,:);
            app.UITable.RowName=num2cell(value-app.n+1:size(app.data,1));
            testButtonEnable(app);
        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
            try
                app.PageDropDown.Value=num2str(str2num(app.PageDropDown.Value)+1);
                value = str2double(app.PageDropDown.Value);
                value = value * app.n;
                app.UITable.Data = app.data(value-app.n+1:end,:);
                app.UITable.RowName=num2cell(value-app.n+1:size(app.data,1));
                testButtonEnable(app);
            catch
                return;
            end
        end

        % Button pushed function: BACKButton
        function BACKButtonPushed(app, event)
            try
                app.PageDropDown.Value=num2str(str2num(app.PageDropDown.Value)-1);
                value = str2double(app.PageDropDown.Value);
                value = value * app.n;
                app.UITable.Data = app.data(value-app.n+1:end,:);
                app.UITable.RowName=num2cell(value-app.n+1:size(app.data,1));
                testButtonEnable(app);   
            catch
                return;
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
        
            if strcmp(app.sql,"select * from papers")
                SQLAPP_2(app,event,app.data);                  
            end 
            
        end

        % Size changed function: SQLsearchTab
        function SQLsearchTabSizeChanged(app, event)
            
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            %string(selectedTab.Title)
            
        end

        % Value changed function: SearchByDropDown
        function SearchByDropDownValueChanged(app, event)
            value = app.SearchByDropDown.Value;
            
        end

        % Value changed function: KeyWords1EditField
        function KeyWords1EditFieldValueChanged(app, event)
            value = app.KeyWords1EditField.Value;
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
        end

        % Button pushed function: FindPapersButton
        function FindPapersButtonPushed(app, event)
            app.SQLTextArea.Value = "select * from papers";            
        end

        % Button pushed function: FindAuthorsButton
        function FindAuthorsButtonPushed(app, event)
            app.SQLTextArea.Value = "select * from authors"; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FILEEditFieldLabel = uilabel(app.UIFigure);
            app.FILEEditFieldLabel.Text = 'FILE';
            app.FILEEditField = uieditfield(app.UIFigure, 'text');
            app.FILEEditField.ValueChangedFcn = createCallbackFcn(app, @FILEEditFieldValueChanged, true);
            app.FILEEditField.Editable = 'off';
            app.FILEEditField.Value = 'database.sqlite';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.SEARCHButton = uibutton(app.UIFigure, 'push');
            app.SEARCHButton.ButtonPushedFcn = createCallbackFcn(app, @SEARCHButtonPushed, true);
            app.SEARCHButton.Text = 'SEARCH';
            app.exitButton = uibutton(app.UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exitButton.Text = 'exit';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.TotalEditFieldLabel = uilabel(app.UIFigure);
            app.TotalEditFieldLabel.Text = 'Total';
            app.TotalEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalEditField.Limits = [0 Inf];
            app.TotalEditField.Editable = 'off';
            app.PageDropDownLabel = uilabel(app.UIFigure);
            app.PageDropDownLabel.Text = 'Page';
            app.PageDropDown = uidropdown(app.UIFigure);
            app.PageDropDown.Items = {};
            app.PageDropDown.ValueChangedFcn = createCallbackFcn(app, @PageDropDownValueChanged, true);
            app.PageDropDown.Value = {};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SQLsearchTab = uitab(app.TabGroup);
            app.SQLsearchTab.SizeChangedFcn = createCallbackFcn(app, @SQLsearchTabSizeChanged, true);
            app.SQLsearchTab.Title = 'SQL search';
            app.SQLTextAreaLabel = uilabel(app.SQLsearchTab);
            app.SQLTextAreaLabel.Text = 'SQL';
            app.SQLTextArea = uitextarea(app.SQLsearchTab);
            app.FindPapersButton = uibutton(app.SQLsearchTab, 'push');
            app.FindPapersButton.ButtonPushedFcn = createCallbackFcn(app, @FindPapersButtonPushed, true);
            app.FindPapersButton.Text = 'Find Papers';
            app.FindAuthorsButton = uibutton(app.SQLsearchTab, 'push');
            app.FindAuthorsButton.ButtonPushedFcn = createCallbackFcn(app, @FindAuthorsButtonPushed, true);
            app.FindAuthorsButton.Text = 'Find Authors';
            app.AutomaticallygeneratecodeLabel = uilabel(app.SQLsearchTab);
            app.AutomaticallygeneratecodeLabel.Text = 'Automatically generate code';
            app.KeyWordssearchTab = uitab(app.TabGroup);
            app.KeyWordssearchTab.Title = 'KeyWords search';
            app.KeyWords1EditFieldLabel = uilabel(app.KeyWordssearchTab);
            app.KeyWords1EditFieldLabel.Text = 'Key Words 1';
            app.KeyWords1EditField = uieditfield(app.KeyWordssearchTab, 'text');
            app.KeyWords1EditField.ValueChangedFcn = createCallbackFcn(app, @KeyWords1EditFieldValueChanged, true);
            app.SearchByLabel = uilabel(app.KeyWordssearchTab);
            app.SearchByLabel.Text = 'Search By';
            app.SearchByDropDown = uidropdown(app.KeyWordssearchTab);
            app.SearchByDropDown.Items = {'name', 'title', 'abstract', 'eventtype'};
            app.SearchByDropDown.ValueChangedFcn = createCallbackFcn(app, @SearchByDropDownValueChanged, true);
            app.SearchByDropDown.Value = 'name';
            app.KeyWords2EditFieldLabel = uilabel(app.KeyWordssearchTab);
            app.KeyWords2EditFieldLabel.Text = 'Key Words 2';
            app.KeyWords2EditField = uieditfield(app.KeyWordssearchTab, 'text');
            app.KeyWords3EditFieldLabel = uilabel(app.KeyWordssearchTab);
            app.KeyWords3EditFieldLabel.Text = 'Key Words 3';
            app.KeyWords3EditField = uieditfield(app.KeyWordssearchTab, 'text');
            app.ButtonGroup = uibuttongroup(app.KeyWordssearchTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.IntersectButton = uiradiobutton(app.ButtonGroup);
            app.IntersectButton.Text = 'Intersect';
            app.IntersectButton.Value = true;
            app.UnionButton = uiradiobutton(app.ButtonGroup);
            app.UnionButton.Text = 'Union';
            app.OrderByDropDownLabel = uilabel(app.KeyWordssearchTab);
            app.OrderByDropDownLabel.Text = 'Order By';
            app.OrderByDropDown = uidropdown(app.KeyWordssearchTab);
            app.OrderByDropDown.Items = {'authors.id', 'name', 'title', 'abstract', 'eventtype'};
            app.OrderByDropDown.Value = 'authors.id';
            app.BACKButton = uibutton(app.UIFigure, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Enable = 'off';
            app.BACKButton.Text = 'BACK';
            app.NEXTButton = uibutton(app.UIFigure, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Enable = 'off';
            app.NEXTButton.Text = 'NEXT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SQLAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end