classdef SQLAPP_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        PaperPanel        matlab.ui.container.Panel
        AbstractLabel     matlab.ui.control.Label
        AbstractTextArea  matlab.ui.control.TextArea
        PapertextLabel    matlab.ui.control.Label
        PaperText         matlab.ui.control.TextArea
        title             matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, otherapp, event, data)
            try                       
                indices = event.Indices;                
                whichPaper=(str2num(otherapp.PageDropDown.Value)-1)*10+indices(1,1);
                app.title.Value = data{whichPaper,2};
                app.AbstractTextArea.Value = data{whichPaper,5};
                app.PaperText.Value=data{whichPaper,6};
            catch
                delete(app);
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PaperPanel = uipanel(app.UIFigure);
            app.PaperPanel.Title = 'Paper';
            app.AbstractLabel = uilabel(app.PaperPanel);
            app.AbstractLabel.Text = 'Abstract';
            app.AbstractTextArea = uitextarea(app.PaperPanel);
            app.PapertextLabel = uilabel(app.PaperPanel);
            app.PapertextLabel.Text = 'Paper text';
            app.PaperText = uitextarea(app.PaperPanel);
            app.title = uitextarea(app.PaperPanel);
            app.title.Editable = 'off';
            app.title.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SQLAPP_2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end